classdef Distortion_SASP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Title                         matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        NonLinearDistortionLabel      matlab.ui.control.Label
        Panel_3                       matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        GridLayout8                   matlab.ui.container.GridLayout
        InterpolationLabel            matlab.ui.control.Label
        InterpSwitch                  matlab.ui.control.Switch
        InputDropDown                 matlab.ui.control.DropDown
        InputDropDownLabel            matlab.ui.control.Label
        TableSizeDropDown             matlab.ui.control.DropDown
        TableSizeLabel                matlab.ui.control.Label
        FrequencyHzEditField          matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel     matlab.ui.control.Label
        GridLayout7                   matlab.ui.container.GridLayout
        InterpolationPlot             matlab.ui.control.UIAxes
        Panel_2                       matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout11                  matlab.ui.container.GridLayout
        GridLayout12                  matlab.ui.container.GridLayout
        WaveshapeDropDown             matlab.ui.control.DropDown
        WaveshapeDropDownLabel        matlab.ui.control.Label
        WaveshapePlot                 matlab.ui.control.UIAxes
        GridLayout10                  matlab.ui.container.GridLayout
        SwitchDistortion              matlab.ui.control.Switch
        GridLayout13                  matlab.ui.container.GridLayout
        DeflectionPointLabel_2        matlab.ui.control.Label
        GainKnob                      matlab.ui.control.Knob
        OrderKnob                     matlab.ui.control.DiscreteKnob
        OrderKnobLabel                matlab.ui.control.Label
        DeflectionPointLabel          matlab.ui.control.Label
        DeflectionKnob                matlab.ui.control.Knob
        GridPlay                      matlab.ui.container.GridLayout
        DurationSpinner               matlab.ui.control.Spinner
        DurationsSpinnerLabel         matlab.ui.control.Label
        DisplayedperiodsSpinner       matlab.ui.control.Spinner
        DisplayedperiodsSpinnerLabel  matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        SwitchOnButton                matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout14                  matlab.ui.container.GridLayout
        LSDvalue                      matlab.ui.control.Label
        LSDLabel                      matlab.ui.control.Label
        THDvalue                      matlab.ui.control.Label
        THDLabel                      matlab.ui.control.Label
        PlotSwitch                    matlab.ui.control.Switch
        InputPlot                     matlab.ui.control.UIAxes
        OutputPlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        autoUpdate % Description
        oldDuration % Description
        samplingFreq % Description
        input_sound % Description
        output_sound % Description
        fft_in % Description
        fft_out % Description
        f0 % Description
        phase % Description
    end
    
    methods (Access = private)
        
        function updatePlot(app)
        %% DEFINITION OF VARIABLES AND TABLES
            Fs=44100;
            app.samplingFreq=Fs;
            duration=5;
            t=0:1:floor(Fs*duration)-1;
            t=t/Fs;
%             t = 0:1/Fs:duration-1/Fs;
%             f = 0:1/duration:Fs/2;

            tableSize = str2double(app.TableSizeDropDown.Value);           % Size of the lookup table
              % Phase values for one cycle

            switch app.InputDropDown.Value
                case 'Sine'
                    theta = linspace(0, 2*pi, tableSize);
                    %Sinusoidal values for one cycle
                    table_samples = -cos(theta); 
                case 'Triangular'
                    theta = linspace(0, 2*pi, tableSize+1);
                    % Triangular wave values for one cycle
                    table_samples=zeros(tableSize+1,1);
                    table_samples(1:tableSize/2)=-1+2*theta(1:tableSize/2)/pi;
                    table_samples(tableSize/2+1:end)=3-2*theta(tableSize/2+1:end)/pi;
                case 'Square'
                    theta = linspace(0, 2*pi, tableSize);
                    % Square wave values for one cycle
                    table_samples=zeros(tableSize,1);
                    turnPoint=floor(tableSize/2);
                    table_samples(1:turnPoint)=-1;
                    table_samples(turnPoint+1:end)=1;

            end


        %% INTERPOLATION
            freq=app.FrequencyHzEditField.Value;
            app.f0=freq;
            interp = interpolation(app,theta,table_samples,freq,Fs,duration);
            interp_sign=interp.a;
            interp_axis=interp.b;
            interp_table=interp.c;

            
            app.input_sound = interp_sign;
        
            
        % INTERPOLATION PLOT
            plot(app.InterpolationPlot, interp_axis, interp_sign(1:length(interp_axis)), 'y-');     
            xticks(app.InterpolationPlot,[0 pi 2*pi]);
            xticklabels(app.InterpolationPlot, {'0', '\pi', '2\pi'});
            xlim(app.InterpolationPlot,[0,2*pi]);
            ylim(app.InterpolationPlot,[-1.1,1.1])
            hold(app.InterpolationPlot, 'on');
            stem(app.InterpolationPlot,theta,interp_table,'ro');
            hold(app.InterpolationPlot, 'off');
%             stem(t_samples,interp_sign,"filled",'r');

        %% DISTORTION 

        switch app.SwitchDistortion.Value
            case 'Waveshape'
                x=-1:0.01:1;
                ord=str2double(app.OrderKnob.Value);
                gain=app.GainKnob.Value;
                switch app.WaveshapeDropDown.Value
                    case 'Sine'
                        shapeFunc=gain*sin(x*pi/2).^ord;
                        waveshape = @(interp_sign, ord, gain) gain*sin(interp_sign*pi/2).^ord;
                    case 'Exp'
                        shapeFunc=gain*exp(x*ord)-gain*(exp(-ord))-1;
                        waveshape = @(interp_sign, ord, gain) gain*exp(ord*interp_sign)-gain*(exp(-ord))-1;
                    case 'Atan'
                        shapeFunc=gain*atan(x*pi/2).^ord;
                        waveshape = @(interp_sign, ord, gain) gain*atan(interp_sign*pi/2).^ord;
                    case 'Pow'
                        shapeFunc=gain*x.^ord;  
                        waveshape = @(interp_sign, ord, gain) gain*interp_sign.^ord;
                    case 'Tanh'
                        shapeFunc=gain*tanh(x*ord*pi);
                        waveshape = @(interp_sign, ord, gain) gain*tanh(interp_sign*ord*pi);
                end
                shapeFunc(shapeFunc>1)=1;
                shapeFunc(shapeFunc<-1)=-1;

                switch app.Lamp.Enable
                    case 'on'
                        dist_sign = waveshape(interp_sign, ord, gain);
                        dist_sign(dist_sign>1)=1;
                        dist_sign(dist_sign<-1)=-1;
                    case 'off'
                        dist_sign = interp_sign;
                end

                % DISTORTION PLOT
                plot(app.WaveshapePlot, x, shapeFunc, 'y-');
                xticks(app.WaveshapePlot,[-1 0 1]);
                xticklabels(app.WaveshapePlot, {'-1', '0', '1'});
                xlim(app.WaveshapePlot,[-1,1]);
                ylim(app.WaveshapePlot,[-1,1])
                

            case 'Phase Distortion'
                phaseDist = tableDistortion(app,theta,tableSize);
                distortedTable = phaseDist.a;
                phi = phaseDist.b;

                switch app.Lamp.Enable
                        case 'on'
                            distortedOutput = interpolation(app,theta,distortedTable,freq,Fs,duration);
                            dist_sign = distortedOutput.a;
                        case 'off'
                            dist_sign = interp_sign;
                end

                % DISTORTION PLOT
                plot(app.WaveshapePlot, theta, distortedTable, 'y-');
                xticks(app.WaveshapePlot,[0 pi 2*pi]);
                xticklabels(app.WaveshapePlot, {'0', '\pi', '2\pi'});
                xlim(app.WaveshapePlot,[0,2*pi]);
                ylim(app.WaveshapePlot,[-1.1,1.1])
        end
        

        dist_sign = dist_sign/max(abs(dist_sign));
        app.output_sound = dist_sign;
        
        
        %%
        f = (0:length(interp_sign)-1)*(Fs/length(interp_sign));

        %% INPUT PLOT

            switch app.PlotSwitch.Value
                case 'Time'
                    plot(app.InputPlot, t, interp_sign, 'y-');
                    if app.phase == 1
                        switch app.InputDropDown.Value
                            case 'Sine'
                                nodist_plot = mod(2*pi*freq*t, 2*pi) / (2*pi);
        %                         in_plot = mod(2*pi*freq*t, 2*pi) / (2*pi);
                                hold(app.InputPlot, 'on')
                                plot(app.InputPlot,t, nodist_plot)
                                hold(app.InputPlot, 'off')
                        end
                    end
                    xlabel(app.InputPlot, 'Time [s]')
                    ylabel(app.InputPlot, 'Amplitude')
                    ylim(app.InputPlot,[-1.1,1.1])
                    xlim(app.InputPlot,[0,app.DisplayedperiodsSpinner.Value/freq])
                case 'Frequency'
                    freq_sign = abs(fft(interp_sign));

                    app.fft_in = freq_sign;
                    plot(app.InputPlot,f(1:floor(length(f)/2)), db(freq_sign(1:floor(length(f)/2))), 'y-');
                    xlabel(app.InputPlot, 'Frequency [Hz]')
                    ylabel(app.InputPlot, 'Amplitude [dB]')
                    ylim(app.InputPlot,[0,100])
                    xlim(app.InputPlot,[1,Fs/2])
            end

       %% OUTPUT PLOT
            
            switch app.PlotSwitch.Value
                case 'Time'
                    plot(app.OutputPlot, t, dist_sign, 'y-');
                    if app.phase == 1
                        switch app.InputDropDown.Value
                            case 'Sine'
                                switch app.Lamp.Enable
                                    case 'on'
                                        dist_plot = phi/2/pi;
                                        n=floor(length(t)/length(dist_plot));
                                        res = length(t)-length(dist_plot)*n;
                                        dist_plot=repmat(dist_plot,1,n);
                                        dist_plot=[dist_plot, dist_plot(1:res)];
                
                                        in_plot = 32/tableSize*pi*t;
                
                                        hold(app.OutputPlot, 'on')
                                        plot(app.OutputPlot,in_plot, dist_plot)
                                        hold(app.OutputPlot, 'off')
                                    case 'off'
                                        hold(app.OutputPlot, 'on')
                                        plot(app.OutputPlot,t, nodist_plot)
                                        hold(app.OutputPlot, 'off')
                                end
                        end     
                    end
                    xlabel(app.OutputPlot, 'Time [s]')
                    ylabel(app.OutputPlot, 'Amplitude')
                    ylim(app.OutputPlot,[-1.1,1.1])
                    xlim(app.OutputPlot,[0,app.DisplayedperiodsSpinner.Value/freq])
                case 'Frequency'
                    dfreq_sign = abs(fft(dist_sign));
                    app.fft_out = dfreq_sign;
                    
                    plot(app.OutputPlot,f(1:floor(length(f)/2)), db(dfreq_sign(1:floor(length(f)/2))), 'y-');
                    xlabel(app.OutputPlot, 'Frequency [Hz]')
                    ylabel(app.OutputPlot, 'Amplitude [dB]')
                    ylim(app.OutputPlot,[0,100])
                    xlim(app.OutputPlot,[1,Fs/2])
            end

             linkaxes([app.InputPlot app.OutputPlot],'x');

        %% THD and LSD

        app.LSDvalue.Text = [num2str(LSD(app)), ' dB']  ;

        app.THDvalue.Text = [num2str(thd(app.output_sound,Fs,10)),' dB'];
            
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % INTERPOLATION FUNCTION

        function output = interpolation(app,theta,wave,target_frequency,Fs,duration)
            switch app.InterpSwitch.Value 
                case 'Linear'
                ord=1;
                case 'Cubic'
                ord=3;
            end

            table_samples=wave;
            period = Fs/target_frequency;
            
            interp_theta2 = 0:2*pi/period:2*pi;             %should use this
            
            step = interp_theta2(2)-interp_theta2(1);
            
            shift=0;
            T0=length(interp_theta2);
            nPeriods=floor(duration*Fs/T0);
            interpolated_signal=zeros(floor(duration*Fs),1);
            count=0;
            
            skippedSamples = floor((T0-period)*nPeriods);
            additionalCycles = floor(skippedSamples/T0);
            finalSamples = skippedSamples - (additionalCycles*T0);
             
            % Concatenation of full periods
            i=1;
            while i*T0-count<duration*Fs+T0
                if ord==1
                    interpolated_signal(1+(i-1)*T0-count:i*T0-count) = interp1(theta,table_samples,interp_theta2-shift,"linear");
                else 
                    interpolated_signal(1+(i-1)*T0-count:i*T0-count) = interp1(theta,table_samples,interp_theta2-shift,"spline");
                end
                if interp_theta2(end)-shift<2*pi
                    gap = 2*pi-interp_theta2(end)+shift;  
                else
                    gap = 2*pi-interp_theta2(end)+shift+step;
                    count=count+1;
                    if i>nPeriods
                        finalSamples=finalSamples+1;
                        if finalSamples>T0
                            finalSamples=finalSamples-T0;
                        end
                    end
                end
                    shift=gap-step;
                    i=i+1;
%                     disp(duration*Fs+count);
            end
            
            
            % Concatenation of the last incomplete period
            fullPeriods=(i-1)*T0-count+1;
            if ord==1
                interpolated_signal(fullPeriods:fullPeriods+finalSamples-1) = interp1(theta,table_samples,interp_theta2(1:finalSamples)-shift,"linear");
            else
                interpolated_signal(fullPeriods:fullPeriods+finalSamples-1) = interp1(theta,table_samples,interp_theta2(1:finalSamples)-shift,"spline");
            end
            output.a=interpolated_signal(1:floor(Fs*duration));
            output.b=interp_theta2;
            output.c=table_samples;

        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % DISTORTED WAVETABLE FUNCTION
                
        function output = tableDistortion(app,theta,tableSize)
            T=2*pi;
            d = 2*T*app.DeflectionKnob.Value;
            output.b = 0;
            switch app.InputDropDown.Value
                case 'Sine'
                    table_samples=zeros(tableSize,1);
                    theta_distorted = mod(theta, T);
                    g = @(x) (((T/2 - d) * (x/d)) .* (x < d)) + (((T/2 - d)*((T - x)/(T - d))) .* (x >= d));
                    distorted_phase = g(theta_distorted) + theta;
                    table_samples = -cos(distorted_phase); 

                    output.b = distorted_phase;
                case 'Triangular' 
                    d=d/3;
                    table_samples=zeros(tableSize+1,1);
                    index=floor(d*tableSize/2);
                    if index>=tableSize
                        index=tableSize-1;
                    end
                    if index==0
                        index=1;
                    end
                    table_samples(1:index)=-1+2*theta(1:index)/theta(index);
                    table_samples(index+1:end)=1-2*(theta(index+1:end)-theta(index))/(2*pi-theta(index));
                case 'Square'
                    d=d/3;
                    table_samples=zeros(tableSize,1);
                    turnPoint=floor(d*tableSize/2);
                    if turnPoint>=tableSize
                        turnPoint=tableSize-1;
                    end
                    if turnPoint==0
                        turnPoint=1;
                    end
                    table_samples(1:turnPoint)=-1;
                    table_samples(turnPoint+1:end)=1;
            end
                  output.a=table_samples; 
        end


        function logdist = LSD(app)
            % Compute Fourier transforms
            fft_signal1 = fft(app.input_sound);
            fft_signal2 = fft(app.output_sound);
            f1=floor(app.f0);
            harmonics=f1*5:f1*5:app.samplingFreq*5;
            % Compute magnitude spectra
            mag_fft_signal1 = abs(fft_signal1(harmonics));
            mag_fft_signal2 = abs(fft_signal2(harmonics));


            logratio = 20 * (log10(mag_fft_signal1)-log10(mag_fft_signal2));
        
        
            % Compute mean square LSD
            mean_square_lsd = mean(logratio .^ 2);
        
            % Compute logdist
            logdist = sqrt(mean_square_lsd);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % Input section
            app.FrequencyHzEditField.Value = 440;
            app.InterpSwitch.Value = 'Linear' ;
            app.FrequencyHzEditField.Enable='on';

            % Waveshape/Distortion section
            app.WaveshapeDropDown.Value='Sine';
            app.OrderKnob.Value='1';
            app.DeflectionKnob.Enable='off';
            app.OrderKnob.Enable='on';
            app.phase=0;
            
            % Bypass
            app.Lamp.Color='0.72,0.25,0.25';
            app.Lamp.Enable='off';
            app.SwitchOnButton.Text='Switch On';
            
            % Right side
            app.PlotSwitch.Value = 'Time';

            app.autoUpdate = 1;
            updatePlot(app)
        end

        % Callback function
        function FrequencyChanged(app, event)
            updatePlot(app)
        end

        % Button pushed function: SwitchOnButton
        function SwitchOnButtonPushed(app, event)
            switch app.Lamp.Enable
                case 'off'
                    app.Lamp.Color='1,0,0';
                    app.Lamp.Enable='on';
                    app.SwitchOnButton.Text='Switch Off';
                case 'on'
                    app.Lamp.Color='0.72,0.25,0.25';
                    app.Lamp.Enable='off';
                    app.SwitchOnButton.Text='Switch On';
            end
            updatePlot(app);
        end

        % Value changed function: InputDropDown
        function InputDropDownValueChanged(app, event)

            switch app.InputDropDown.Value 
                case 'Sine'
                switch app.SwitchDistortion.Value
                    case 'Waveshape'
                         app.phase = 0;
                    case 'Phase Distorion'
                         app.phase=1;
                end
            end
            updatePlot(app);
        end

        % Value changed function: PlotSwitch
        function PlotSwitchValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: WaveshapeDropDown
        function WaveshapeDropDownValueChanged(app, event)
           updatePlot(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: OrderKnob
        function OrderKnobValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: TableSizeDropDown
        function TableSizeDropDownValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: InterpSwitch
        function InterpSwitchValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: SwitchDistortion
        function SwitchDistortionValueChanged(app, event)
            newTitle = app.SwitchDistortion.Value;
            title(app.WaveshapePlot, newTitle);
            switch newTitle
                case 'Waveshape'
                    app.DeflectionKnob.Enable='off';
                    app.OrderKnob.Enable='on';
                    app.WaveshapeDropDown.Enable='on';
                    app.phase=0;
                case 'Phase Distortion'
                    app.DeflectionKnob.Enable='on';
                    app.OrderKnob.Enable='off';
                    app.WaveshapeDropDown.Enable='off';
                    switch app.WaveshapeDropDown.Value
                        case 'Sine'
                           app.phase=1;
                    end
            end

            updatePlot(app);
        end

        % Value changed function: DisplayedperiodsSpinner
        function DisplayedperiodsSpinnerValueChanged(app, event)
            updatePlot(app)
        end

        % Callback function
        function LockCheckBoxValueChanged(app, event)

        end

        % Value changed function: DeflectionKnob
        function DeflectionKnobValueChanged(app, event)
            updatePlot(app)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            switch app.Lamp.Enable
                case 'off'
                    music = app.input_sound;
                case 'on'
                    music = app.output_sound;  
            end
            music = music(1:app.DurationSpinner.Value*app.samplingFreq);
            music = music/max(abs(music));
            sound(music,app.samplingFreq);
        end

        % Value changed function: GainKnob
        function GainKnobValueChanged(app, event)
            updatePlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.OutputPlot = uiaxes(app.GridLayout);
            title(app.OutputPlot, 'Output')
            xlabel(app.OutputPlot, 'Time [s]')
            ylabel(app.OutputPlot, 'Amplitude')
            zlabel(app.OutputPlot, 'Z')
            app.InputPlot = uiaxes(app.GridLayout);
            title(app.InputPlot, 'Input')
            xlabel(app.InputPlot, 'Time [s]')
            ylabel(app.InputPlot, 'Amplitude')
            zlabel(app.InputPlot, 'Z')
            app.PlotSwitch = uiswitch(app.GridLayout, 'slider');
            app.PlotSwitch.Items = {'Time', 'Frequency'};
            app.PlotSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSwitchValueChanged, true);
            app.PlotSwitch.Value = 'Time';
            app.GridLayout14 = uigridlayout(app.GridLayout);
            app.THDLabel = uilabel(app.GridLayout14);
            app.THDLabel.Text = 'THD';
            app.THDvalue = uilabel(app.GridLayout14);
            app.THDvalue.Text = '0';
            app.LSDLabel = uilabel(app.GridLayout14);
            app.LSDLabel.Text = 'LSD';
            app.LSDvalue = uilabel(app.GridLayout14);
            app.LSDvalue.Text = '0';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.GridPlay = uigridlayout(app.GridLayout3);
            app.SwitchOnButton = uibutton(app.GridPlay, 'push');
            app.SwitchOnButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchOnButtonPushed, true);
            app.SwitchOnButton.Text = 'Switch On';
            app.Lamp = uilamp(app.GridPlay);
            app.PlayButton = uibutton(app.GridPlay, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = '▶';
            app.DisplayedperiodsSpinnerLabel = uilabel(app.GridPlay);
            app.DisplayedperiodsSpinnerLabel.Text = 'Displayed periods';
            app.DisplayedperiodsSpinner = uispinner(app.GridPlay);
            app.DisplayedperiodsSpinner.Limits = [1 1000];
            app.DisplayedperiodsSpinner.ValueChangedFcn = createCallbackFcn(app, @DisplayedperiodsSpinnerValueChanged, true);
            app.DisplayedperiodsSpinner.Value = 1;
            app.DurationsSpinnerLabel = uilabel(app.GridPlay);
            app.DurationsSpinnerLabel.Text = 'Duration [s]';
            app.DurationSpinner = uispinner(app.GridPlay);
            app.DurationSpinner.Limits = [1 5];
            app.DurationSpinner.Value = 1;
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.GridLayout13 = uigridlayout(app.GridLayout10);
            app.DeflectionKnob = uiknob(app.GridLayout13, 'continuous');
            app.DeflectionKnob.Limits = [0.001 0.5];
            app.DeflectionKnob.MajorTicks = [0.001 0.1008 0.2006 0.3004 0.4002 0.5];
            app.DeflectionKnob.MajorTickLabels = {'min', '', '', '', '', 'max'};
            app.DeflectionKnob.ValueChangedFcn = createCallbackFcn(app, @DeflectionKnobValueChanged, true);
            app.DeflectionKnob.MinorTicks = [0.001 0.026 0.0509 0.0759 0.1008 0.1258 0.1507 0.1757 0.2006 0.2255 0.2505 0.2754 0.3004 0.3254 0.3503 0.3753 0.4002 0.4252 0.4501 0.475 0.5];
            app.DeflectionKnob.Value = 0.001;
            app.DeflectionPointLabel = uilabel(app.GridLayout13);
            app.DeflectionPointLabel.Text = 'Deflection Point';
            app.OrderKnobLabel = uilabel(app.GridLayout13);
            app.OrderKnobLabel.Text = 'Order';
            app.OrderKnob = uiknob(app.GridLayout13, 'discrete');
            app.OrderKnob.Items = {'1', '2', '3', '4', '5', '6'};
            app.OrderKnob.ValueChangedFcn = createCallbackFcn(app, @OrderKnobValueChanged, true);
            app.OrderKnob.Value = '1';
            app.GainKnob = uiknob(app.GridLayout13, 'continuous');
            app.GainKnob.Limits = [1 3];
            app.GainKnob.MajorTicks = [1 1.5 2 2.5 3];
            app.GainKnob.MajorTickLabels = {'min', '', '', '', 'max'};
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainKnobValueChanged, true);
            app.GainKnob.MinorTicks = [1 1.125 1.25 1.375 1.5 1.625 1.75 1.875 2 2.125 2.25 2.375 2.5 2.625 2.75 2.875 3];
            app.GainKnob.Value = 1;
            app.DeflectionPointLabel_2 = uilabel(app.GridLayout13);
            app.DeflectionPointLabel_2.Text = 'Gain';
            app.SwitchDistortion = uiswitch(app.GridLayout10, 'slider');
            app.SwitchDistortion.Items = {'Waveshape', 'Phase Distortion'};
            app.SwitchDistortion.ValueChangedFcn = createCallbackFcn(app, @SwitchDistortionValueChanged, true);
            app.SwitchDistortion.Value = 'Waveshape';
            app.GridLayout11 = uigridlayout(app.GridLayout5);
            app.WaveshapePlot = uiaxes(app.GridLayout11);
            title(app.WaveshapePlot, 'Waveshape')
            xlabel(app.WaveshapePlot, 'X')
            ylabel(app.WaveshapePlot, 'Y')
            zlabel(app.WaveshapePlot, 'Z')
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.WaveshapeDropDownLabel = uilabel(app.GridLayout12);
            app.WaveshapeDropDownLabel.Text = 'Waveshape';
            app.WaveshapeDropDown = uidropdown(app.GridLayout12);
            app.WaveshapeDropDown.Items = {'Sine', 'Exp', 'Atan', 'Pow', 'Tanh'};
            app.WaveshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveshapeDropDownValueChanged, true);
            app.WaveshapeDropDown.Value = 'Sine';
            app.Panel_3 = uipanel(app.UIFigure);
            app.GridLayout6 = uigridlayout(app.Panel_3);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.InterpolationPlot = uiaxes(app.GridLayout7);
            title(app.InterpolationPlot, 'Interpolation')
            zlabel(app.InterpolationPlot, 'Z')
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.GridLayout8 = uigridlayout(app.GridLayout9);
            app.FrequencyHzEditFieldLabel = uilabel(app.GridLayout8);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.GridLayout8, 'numeric');
            app.FrequencyHzEditField.Limits = [20 4000];
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 100;
            app.TableSizeLabel = uilabel(app.GridLayout8);
            app.TableSizeLabel.Text = 'Table Size';
            app.TableSizeDropDown = uidropdown(app.GridLayout8);
            app.TableSizeDropDown.Items = {'8', '16', '32', '64', '128', '256', '512', '1024'};
            app.TableSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @TableSizeDropDownValueChanged, true);
            app.TableSizeDropDown.Value = '8';
            app.InputDropDownLabel = uilabel(app.GridLayout8);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.GridLayout8);
            app.InputDropDown.Items = {'Sine', 'Triangular', 'Square'};
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownValueChanged, true);
            app.InputDropDown.Value = 'Sine';
            app.InterpSwitch = uiswitch(app.GridLayout8, 'slider');
            app.InterpSwitch.Items = {'Linear', 'Cubic'};
            app.InterpSwitch.ValueChangedFcn = createCallbackFcn(app, @InterpSwitchValueChanged, true);
            app.InterpSwitch.Value = 'Linear';
            app.InterpolationLabel = uilabel(app.GridLayout8);
            app.InterpolationLabel.Text = 'Interpolation';
            app.Title = uipanel(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.Title);
            app.NonLinearDistortionLabel = uilabel(app.GridLayout4);
            app.NonLinearDistortionLabel.Text = 'Non-Linear Distortion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Distortion_SASP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end